classdef Motioncontrolv3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        LockinPanel             matlab.ui.container.Panel
        ResetGraphButton        matlab.ui.control.StateButton
        MoveandRecordButton     matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
        MotionControlPanel      matlab.ui.container.Panel
        ExperimentalTestButton  matlab.ui.control.Button
        StopImmediateButton     matlab.ui.control.Button
        StartButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        motion; % NRT100
        forceSensor; % Lock in Amplifier
        lockin; % Lock in Object
        Amplitude = zeros(100,1); % List of Amplitude(R) displayed
        Position = zeros(100,1) % List of Position displayed
        counter = 0;
        eventlistener; %Listens to event
        inc = 0.2;
        slope;
    end
    
    methods (Access = private)
        
        function StageActivation(app)
            fpos    = get(0,'DefaultFigurePosition'); % figure default position
            fpos(3) = 650*2/3; % figure window size;Width
            fpos(4) = 450*2/3; % Height

            fig = figure('Position', fpos,...
               'Menu','None',...
               'Name','APT GUI');
            app.motion = actxcontrol('MGMOTOR.MGMotorCtrl.1',[20 20 400 400*2/3 ], fig);
            app.motion.StartCtrl;
            SN2 = 90914852;
            set(app.motion,'HWSerialNum', SN2);
            jFrame2 = get(fig,'JavaFrame');
            jFrame2.setMinimized(true);
            app.motion.SetAbsMovePos(0, 65);
            app.motion.SetVelParams(0,0, 0.5 , 1.5);
            app.motion.SetRelMoveDist(0, app.inc);

        end
        
        function LockIn(app)
            % Use instrfind to find existing instrument objects
            app.lockin = instrfind('Type', 'visa-gpib', 'RsrcName', 'GPIB0::8::INSTR', 'Tag', '');
            % If there are no existing objects q, create a new one
            if isempty(app.lockin)
                app.lockin = visa('ni', 'GPIB0::8::INSTR');
            end
            fopen(app.lockin);
        end
        
        function DisplayRecordAmp(app)
            fprintf(app.lockin, 'OUTP? 3');
            app.counter = app.counter + 1;
            app.Amplitude(app.counter) = fscanf(app.lockin, '%f');
            app.Position(app.counter) = app.motion.GetPosition_Position(0);
            xlabel(app.UIAxes,'Position');
            ylabel(app.UIAxes,'Amplitude');
            plot(app.UIAxes,app.Position(1:app.counter), app.Amplitude(1:app.counter));
        end
        function Optimizetest(app)
            app.DisplayRecordAmp();
            app.motion.MoveRelative(0, true);
            app.DisplayRecordAmp();
            Prevslope = app.FindSlope();
            app.DisplayRecordAmp();
            app.slope = app.FindSlope();
            while(app.slope >= 0.85 * Prevslope)
                app.motion.MoveRelative(0, true);
                pause(1);
                app.DisplayRecordAmp();
                Prevslope = app.slope;
                app.slope = app.FindSlope();
            end
            for i = 1:3
                app.motion.MoveRelative(0, true);
                pause(1);
                app.DisplayRecordAmp();
            end

            while(abs(app.inc) > 0.005)
                app.inc = app.inc / 2;
                app.motion.SetRelMoveDist(0, app.inc);
                app.motion.MoveRelative(0, true);
                pause(1);
                app.DisplayRecordAmp();
                Prevslope = app.slope;
                app.slope = app.FindSlope();
                if ((app.slope - Prevslope)/ (app.Position(app.counter) - app.Position(app.counter - 1)) < 0)
                    app.inc = app.inc * -1;
                end
            end
        end
        function newslope = FindSlope(app)
            newslope = (app.Amplitude(app.counter) - app.Amplitude(app.counter -1)) / (app.Position(app.counter) - app.Position(app.counter -1));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.LockIn();
            app.StageActivation();
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.motion.MoveHome(0,1); 
            app.motion.MoveAbsolute(0,1);
            %app.eventlistener = addlistener(app.motion, 'MoveComplete', @moveCompleteCallback);

        end

        % Button pushed function: MoveandRecordButton
        function MoveandRecordButtonPushed(app, event)
            app.motion.MoveRelative(0, true);
            app.DisplayRecordAmp();

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            fclose(app.lockin);
            delete(app.motion);
            delete(app);
            
        end

        % Value changed function: ResetGraphButton
        function ResetGraphButtonValueChanged(app, event)
            app.Amplitude = zeros(100,1);
            app.Position = zeros(100,1);
            app.counter = 1;
        end

        % Button pushed function: StopImmediateButton
        function StopImmediateButtonPushed(app, event)
            app.motion.StopImmediate(0);
        end

        % Button pushed function: ExperimentalTestButton
        function ExperimentalTestButtonPushed(app, event)
            app.Optimizetest();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.MotionControlPanel = uipanel(app.UIFigure);
            app.MotionControlPanel.Title = 'Motion Control';
            app.StartButton = uibutton(app.MotionControlPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopImmediateButton = uibutton(app.MotionControlPanel, 'push');
            app.StopImmediateButton.ButtonPushedFcn = createCallbackFcn(app, @StopImmediateButtonPushed, true);
            app.StopImmediateButton.Text = 'StopImmediate';
            app.ExperimentalTestButton = uibutton(app.MotionControlPanel, 'push');
            app.ExperimentalTestButton.ButtonPushedFcn = createCallbackFcn(app, @ExperimentalTestButtonPushed, true);
            app.ExperimentalTestButton.Text = 'Experimental Test';
            app.LockinPanel = uipanel(app.UIFigure);
            app.LockinPanel.Title = 'Lockin';
            app.UIAxes = uiaxes(app.LockinPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.MoveandRecordButton = uibutton(app.LockinPanel, 'push');
            app.MoveandRecordButton.ButtonPushedFcn = createCallbackFcn(app, @MoveandRecordButtonPushed, true);
            app.MoveandRecordButton.Text = 'Move and Record';
            app.ResetGraphButton = uibutton(app.LockinPanel, 'state');
            app.ResetGraphButton.ValueChangedFcn = createCallbackFcn(app, @ResetGraphButtonValueChanged, true);
            app.ResetGraphButton.Text = 'Reset Graph';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Motioncontrolv3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end